classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MainTable                     matlab.ui.container.TabGroup
        EastGroupTab                  matlab.ui.container.Tab
        StatusPanel_Group1            matlab.ui.container.Panel
        Label_ControlStatus_Group1    matlab.ui.control.Label
        Label_FallDetection_Group1    matlab.ui.control.Label
        AutoLampLabel_2               matlab.ui.control.Label
        Lamp_Auto_Group1              matlab.ui.control.Lamp
        ManualLampLabel_2             matlab.ui.control.Label
        Lamp_Manual_Group1            matlab.ui.control.Lamp
        OnLampLabel_2                 matlab.ui.control.Label
        Lamp_On_Group1                matlab.ui.control.Lamp
        OffLampLabel_2                matlab.ui.control.Label
        Lamp_Off_Group1               matlab.ui.control.Lamp
        Label_FallDetection_Group1_2  matlab.ui.control.Label
        OnLampLabel_3                 matlab.ui.control.Label
        Lamp_On_Group1_2              matlab.ui.control.Lamp
        CloseLabel                    matlab.ui.control.Label
        Lamp_Off_Group1_2             matlab.ui.control.Lamp
        LouverAnglePanel_Group1       matlab.ui.container.Panel
        ManualControlSwitchLabel_2    matlab.ui.control.Label
        ManualSwitch_Group1           matlab.ui.control.RockerSwitch
        OpenButton_G1                 matlab.ui.control.Button
        CloseButton_G1                matlab.ui.control.Button
        STOPG1                        matlab.ui.control.Button
        StateChangeLogPanel_Group1    matlab.ui.container.Panel
        UITable_Group1                matlab.ui.control.Table
        WeatherConditionPanel         matlab.ui.container.Panel
        Label_SolarRadiation          matlab.ui.control.Label
        Label_SkyCondition            matlab.ui.control.Label
        Label_TimeDate                matlab.ui.control.Label
        Icon1                         matlab.ui.control.Image
        RHG1                          matlab.ui.control.Label
        TempG1                        matlab.ui.control.Label
        ImgG1                         matlab.ui.control.Image
        WestGroupTab                  matlab.ui.container.Tab
        StatusPanel_Group2            matlab.ui.container.Panel
        Label_ControlStatus_Group2    matlab.ui.control.Label
        Label_FallDetection_Group2    matlab.ui.control.Label
        AutoLampLabel                 matlab.ui.control.Label
        Lamp_Auto_Group2              matlab.ui.control.Lamp
        ManualLampLabel               matlab.ui.control.Label
        Lamp_Manual_Group2            matlab.ui.control.Lamp
        OnLampLabel                   matlab.ui.control.Label
        Lamp_On_Group2                matlab.ui.control.Lamp
        OffLampLabel                  matlab.ui.control.Label
        Lamp_Off_Group2               matlab.ui.control.Lamp
        Label_FallDetection_Group1_3  matlab.ui.control.Label
        OnLampLabel_4                 matlab.ui.control.Label
        Lamp_On_Group1_3              matlab.ui.control.Lamp
        CloseLabel_2                  matlab.ui.control.Label
        Lamp_Off_Group1_3             matlab.ui.control.Lamp
        StateChangeLogPanel_Group2    matlab.ui.container.Panel
        UITable_Group2                matlab.ui.control.Table
        LouverAnglePanel_Group2       matlab.ui.container.Panel
        ManualControlSwitchLabel_3    matlab.ui.control.Label
        ManualSwitch_Group2           matlab.ui.control.RockerSwitch
        OpenButton_G2                 matlab.ui.control.Button
        CloseButton_G2                matlab.ui.control.Button
        STOPG1_2                      matlab.ui.control.Button
        WeatherConditionPanel_2       matlab.ui.container.Panel
        Label_SolarRadiation_2        matlab.ui.control.Label
        Label_SkyCondition_2          matlab.ui.control.Label
        Label_TimeDate_2              matlab.ui.control.Label
        Icon2                         matlab.ui.control.Image
        RHG2                          matlab.ui.control.Label
        TempG2                        matlab.ui.control.Label
        ImgG2                         matlab.ui.control.Image
        Label_IW                      matlab.ui.control.Label
        UpdateButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         %read data from simulink

         temp = xlsread('weather.csv','B9:B9');
         RH = xlsread('weather.csv','B13:B13');
         weather = xlsread('weather.csv','B25:B25'); 
         %temp
         app.TempG1.Text=num2str(temp);
         app.TempG2.Text=num2str(temp);
         %RH
         app.RHG1.Text= num2str(RH);
         app.RHG2.Text= num2str(RH);
         
                
         % Sky Condition
         if isequal(weather,0)
             app.Icon1.ImageSource = 'sun.png';
             app.Icon2.ImageSource='sun.png';
         elseif isequal(weather,1)
             app.Icon1.ImageSource = 'cloud_full.png';
             app.Icon2.ImageSource = 'cloud_full.png';
         else
             app.Icon1.ImageSource ='Cloud.png';
             app.Icon2.ImageSource ='Cloud.png';
         end

         % check manual control state 
            % Group2 manual
                valueManualIni2 = app.ManualSwitch_Group2.Value;
                if isequal(valueManualIni2,'On')
                    app.Lamp_Auto_Group2.Color = 'White';
                    app.Lamp_Manual_Group2.Color = 'Green';
                    app.OpenButton_G2.Enable = 'On';
                    app.CloseButton_G2.Enable = 'On';
                    
                else 
                    app.Lamp_Auto_Group2.Color = 'Green';
                    app.Lamp_Manual_Group2.Color = 'White';
                    app.OpenButton_G2.Enable = 'Off';
                    app.CloseButton_G2.Enable = 'Off';
                    
                end
                 % Group1 manul
                valueManualIni1 = app.ManualSwitch_Group1.Value;
                if isequal(valueManualIni1,'On')
                    app.Lamp_Auto_Group1.Color = 'White';
                    app.Lamp_Manual_Group1.Color = 'Green';
                    app.OpenButton_G1.Enable = 'On';
                    app.CloseButton_G1.Enable = 'On';
                    
                else 
                    app.Lamp_Auto_Group1.Color = 'Green';
                    app.Lamp_Manual_Group1.Color = 'White';
                    app.OpenButton_G1.Enable = 'Off';
                    app.CloseButton_G1.Enable = 'Off';
                   
                end
            %check fall detection
                app.Lamp_Off_Group2.Color = 'White';
                app.Lamp_Off_Group1.Color = 'White';


        end

        % Value changed function: ManualSwitch_Group2
        function ManualSwitch_Group2ValueChanged(app, event)
            valueManualCha2 = app.ManualSwitch_Group2.Value;
                if isequal(valueManualCha2,'On')
                    app.Lamp_Auto_Group2.Color = 'White';
                    app.Lamp_Manual_Group2.Color = 'Green';
                    app.OpenButton_G2.Enable = 'On';
                    app.CloseButton_G2.Enable = 'On';
                    M2_current = 1;
                else 
                    app.Lamp_Auto_Group2.Color = 'Green';
                    app.Lamp_Manual_Group2.Color = 'White';
                    app.OpenButton_G2.Enable = 'Off';
                    app.CloseButton_G2.Enable = 'Off';
                    M2_current = 0;
                end
               xlswrite('G2M_current.xls',M2_current,'Sheet1','A1');
               xlsappend('G2M_log.xls',M2_current,'Sheet1');
                    
        end

        % Value changed function: ManualSwitch_Group1
        function ManualSwitch_Group1ValueChanged(app, event)
            valueManualCha1 = app.ManualSwitch_Group1.Value;
                if isequal(valueManualCha1,'On')
                    app.Lamp_Auto_Group1.Color = 'White';
                    app.Lamp_Manual_Group1.Color = 'Green';
                    app.OpenButton_G1.Enable = 'On';
                    app.CloseButton_G1.Enable = 'On';
                    M1_current = 1;
                else 
                    app.Lamp_Auto_Group1.Color = 'Green';
                    app.Lamp_Manual_Group1.Color = 'White';
                    app.OpenButton_G1.Enable = 'Off';
                    app.CloseButton_G1.Enable = 'Off';
                    M1_current = 0;
                end
                timeG1Mc=[M1_current,clock];
               xlswrite('G1M_current.xls',M1_current,'Sheet1','A1');
               xlsappend('G1M_log.xls',timeG1Mc,'Sheet1');
        end

        % Button pushed function: OpenButton_G1
        function OpenButton_G1Pushed(app, event)
            xlswrite("G1_status_current.xls",1,'Sheet1','A1');
            xlswrite("G1_status_current.xls",1,'Sheet1','B1');%add
            G1_status_flag = 1;
            timeG1AOpen = [1,1,clock];%change
            xlsappend('G1_status_log.xls',timeG1AOpen,'Sheet1');
            app.ManualSwitch_Group1.Enable= 'Off';
            app.OpenButton_G1.Enable = 'Off';
            app.CloseButton_G1.Enable = 'Off';
        end

        % Button pushed function: CloseButton_G1
        function CloseButton_G1Pushed(app, event)
            xlswrite("G1_status_current.xls",2,'Sheet1','A1');
            xlswrite("G1_status_current.xls",1,'Sheet1','B1');%add
            G1_status_flag = 2;
            timeG1Close = [2,1,clock];
            xlsappend('G1_status_log.xls',timeG1Close,'Sheet1');
            app.ManualSwitch_Group1.Enable= 'Off';
            app.OpenButton_G1.Enable = 'Off';
            app.CloseButton_G1.Enable = 'Off';
        end

        % Button pushed function: OpenButton_G2
        function OpenButton_G2Pushed(app, event)
            xlswrite("G2_status_current.xls",1,'Sheet1','A1');
            xlswrite("G2_status_current.xls",1,'Sheet1','B1');%add
            G2_status_flag = 1;
            timeG2Open = [1,1,clock];
            xlsappend('G2_status_log.xls',timeG2Open,'Sheet1');
            app.ManualSwitch_Group2.Enable= 'Off';
            app.CloseButton_G2.Enable = 'Off';
            app.OpenButton_G2.Enable = 'Off';
        end

        % Button pushed function: CloseButton_G2
        function CloseButton_G2Pushed(app, event)
            xlswrite("G2_status_current.xls",2,'Sheet1','A1');
            xlswrite("G2_status_current.xls",1,'Sheet1','B1');%add
            G2_status_flag = 2;
            timeG2Close = [2,1,clock];
            xlsappend('G2_status_log.xls',timeG2Close,'Sheet1'); 
            app.ManualSwitch_Group2.Enable= 'Off';
            app.OpenButton_G2.Enable = 'Off';
            app.CloseButton_G2.Enable = 'Off';
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
             %read data from simulink
         temp = xlsread('weather.csv','B9:B9');
         RH = xlsread('weather.csv','B13:B13');
         weather = xlsread('weather.csv','B25:B25');   
         %temp
         app.TempG1.Value= temp;
         app.TempG2.Value=temp;
         %RH
         app.RHG1.Value= RH;
         app.RHG2.Value= RH;
         % Sky Condition
         if isequal(weather,0)
             app.Icon1.ImageSource = 'sun.png';
             app.Icon2.ImageSource='sun.png';
         elseif isequal(weather,1)
             app.Icon1.ImageSource = 'cloud_full.png';
             app.Icon2.ImageSource = 'cloud_full.png';
         else
             app.Icon1.ImageSource ='Cloud.png';
             app.Icon2.ImageSource ='Cloud.png';
         end

         % check manual control state 
            % Group2 manual
                valueManualIni2 = app.ManualSwitch_Group2.Value;
                if isequal(valueManualIni2,'On')
                    app.Lamp_Auto_Group2.Color = 'White';
                    app.Lamp_Manual_Group2.Color = 'Green';
                    app.OpenButton_G2.Enable = 'On';
                    app.CloseButton_G2.Enable = 'On';
                    
                else 
                    app.Lamp_Auto_Group2.Color = 'Green';
                    app.Lamp_Manual_Group2.Color = 'White';
                    app.OpenButton_G2.Enable = 'Off';
                    app.CloseButton_G2.Enable = 'Off';
                    
                end
                 % Group1 manul
                valueManualIni1 = app.ManualSwitch_Group1.Value;
                if isequal(valueManualIni1,'On')
                    app.Lamp_Auto_Group1.Color = 'White';
                    app.Lamp_Manual_Group1.Color = 'Green';
                    app.OpenButton_G1.Enable = 'On';
                    app.CloseButton_G1.Enable = 'On';
                    
                else 
                    app.Lamp_Auto_Group1.Color = 'Green';
                    app.Lamp_Manual_Group1.Color = 'White';
                    app.OpenButton_G1.Enable = 'Off';
                    app.CloseButton_G1.Enable = 'Off';
                   
                end
            %check fall detection
                app.Lamp_Off_Group2.Color = 'White';
                app.Lamp_Off_Group1.Color = 'White';
                
        end

        % Button pushed function: STOPG1
        function STOPG1ButtonPushed(app, event)
            %xlswrite("G1_status_current.xls",0,'Sheet1','A1');
            xlswrite("G1_status_current.xls",0,'Sheet1','B1');%add
            timeG1AOpen = [0,-1,clock];%change
            xlsappend('G1_status_log.xls',timeG1AOpen,'Sheet1');
            app.ManualSwitch_Group1.Enable= 'On';
            app.OpenButton_G1.Enable = 'On';
            app.CloseButton_G1.Enable = 'On';
        end

        % Button pushed function: STOPG1_2
        function STOPG1_2ButtonPushed(app, event)
            %xlswrite("G2_status_current.xls",0,'Sheet1','A1');
            xlswrite("G2_status_current.xls",0,'Sheet1','B1');%add
            timeG2Close = [0,-1,clock];
            xlsappend('G1_status_log.xls',timeG2Close,'Sheet1');
            app.ManualSwitch_Group2.Enable= 'On';
            app.OpenButton_G2.Enable = 'On';
            app.CloseButton_G2.Enable = 'On';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainTable = uitabgroup(app.UIFigure);
            app.EastGroupTab = uitab(app.MainTable);
            app.EastGroupTab.Title = 'East Group';
            app.StatusPanel_Group1 = uipanel(app.EastGroupTab);
            app.StatusPanel_Group1.Title = 'Status';
            app.Label_ControlStatus_Group1 = uilabel(app.StatusPanel_Group1);
            app.Label_ControlStatus_Group1.Text = 'Control Status';
            app.Label_FallDetection_Group1 = uilabel(app.StatusPanel_Group1);
            app.Label_FallDetection_Group1.Text = 'Fault Detection';
            app.AutoLampLabel_2 = uilabel(app.StatusPanel_Group1);
            app.AutoLampLabel_2.Text = 'Auto';
            app.Lamp_Auto_Group1 = uilamp(app.StatusPanel_Group1);
            app.ManualLampLabel_2 = uilabel(app.StatusPanel_Group1);
            app.ManualLampLabel_2.Text = 'Manual';
            app.Lamp_Manual_Group1 = uilamp(app.StatusPanel_Group1);
            app.OnLampLabel_2 = uilabel(app.StatusPanel_Group1);
            app.OnLampLabel_2.Text = 'On';
            app.Lamp_On_Group1 = uilamp(app.StatusPanel_Group1);
            app.OffLampLabel_2 = uilabel(app.StatusPanel_Group1);
            app.OffLampLabel_2.Text = 'Off';
            app.Lamp_Off_Group1 = uilamp(app.StatusPanel_Group1);
            app.Label_FallDetection_Group1_2 = uilabel(app.StatusPanel_Group1);
            app.Label_FallDetection_Group1_2.Text = 'Louver Status';
            app.OnLampLabel_3 = uilabel(app.StatusPanel_Group1);
            app.OnLampLabel_3.Text = 'Open';
            app.Lamp_On_Group1_2 = uilamp(app.StatusPanel_Group1);
            app.CloseLabel = uilabel(app.StatusPanel_Group1);
            app.CloseLabel.Text = 'Close';
            app.Lamp_Off_Group1_2 = uilamp(app.StatusPanel_Group1);
            app.LouverAnglePanel_Group1 = uipanel(app.EastGroupTab);
            app.LouverAnglePanel_Group1.Title = 'Louver Angle';
            app.ManualControlSwitchLabel_2 = uilabel(app.LouverAnglePanel_Group1);
            app.ManualControlSwitchLabel_2.Text = 'Manual Control';
            app.ManualSwitch_Group1 = uiswitch(app.LouverAnglePanel_Group1, 'rocker');
            app.ManualSwitch_Group1.ValueChangedFcn = createCallbackFcn(app, @ManualSwitch_Group1ValueChanged, true);
            app.OpenButton_G1 = uibutton(app.LouverAnglePanel_Group1, 'push');
            app.OpenButton_G1.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_G1Pushed, true);
            app.OpenButton_G1.Icon = 'Open.png';
            app.OpenButton_G1.Text = 'Open';
            app.CloseButton_G1 = uibutton(app.LouverAnglePanel_Group1, 'push');
            app.CloseButton_G1.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_G1Pushed, true);
            app.CloseButton_G1.Icon = 'Close.png';
            app.CloseButton_G1.Text = 'Close';
            app.STOPG1 = uibutton(app.LouverAnglePanel_Group1, 'push');
            app.STOPG1.ButtonPushedFcn = createCallbackFcn(app, @STOPG1ButtonPushed, true);
            app.STOPG1.Icon = 'stop.png';
            app.STOPG1.Text = '';
            app.StateChangeLogPanel_Group1 = uipanel(app.EastGroupTab);
            app.StateChangeLogPanel_Group1.Title = 'State Change Log';
            app.UITable_Group1 = uitable(app.StateChangeLogPanel_Group1);
            app.UITable_Group1.ColumnName = {'Update'; 'Time'; 'IP'; 'Last Status'};
            app.UITable_Group1.RowName = {};
            app.WeatherConditionPanel = uipanel(app.EastGroupTab);
            app.WeatherConditionPanel.Title = 'Weather Condition';
            app.Label_SolarRadiation = uilabel(app.WeatherConditionPanel);
            app.Label_SolarRadiation.Text = 'RH (%)';
            app.Label_SkyCondition = uilabel(app.WeatherConditionPanel);
            app.Label_SkyCondition.Text = 'Sky Condition';
            app.Label_TimeDate = uilabel(app.WeatherConditionPanel);
            app.Label_TimeDate.Text = 'Temperature (°F)';
            app.Icon1 = uiimage(app.WeatherConditionPanel);
            app.RHG1 = uilabel(app.WeatherConditionPanel);
            app.RHG1.Text = 'RHG1';
            app.TempG1 = uilabel(app.WeatherConditionPanel);
            app.TempG1.Text = 'TempG1';
            app.ImgG1 = uiimage(app.EastGroupTab);
            app.ImgG1.ImageSource = 'LouverGroup1.png';
            app.WestGroupTab = uitab(app.MainTable);
            app.WestGroupTab.Title = 'West Group';
            app.StatusPanel_Group2 = uipanel(app.WestGroupTab);
            app.StatusPanel_Group2.Title = 'Status';
            app.Label_ControlStatus_Group2 = uilabel(app.StatusPanel_Group2);
            app.Label_ControlStatus_Group2.Text = 'Control Status';
            app.Label_FallDetection_Group2 = uilabel(app.StatusPanel_Group2);
            app.Label_FallDetection_Group2.Text = 'Fault Detection';
            app.AutoLampLabel = uilabel(app.StatusPanel_Group2);
            app.AutoLampLabel.Text = 'Auto';
            app.Lamp_Auto_Group2 = uilamp(app.StatusPanel_Group2);
            app.ManualLampLabel = uilabel(app.StatusPanel_Group2);
            app.ManualLampLabel.Text = 'Manual';
            app.Lamp_Manual_Group2 = uilamp(app.StatusPanel_Group2);
            app.OnLampLabel = uilabel(app.StatusPanel_Group2);
            app.OnLampLabel.Text = 'On';
            app.Lamp_On_Group2 = uilamp(app.StatusPanel_Group2);
            app.OffLampLabel = uilabel(app.StatusPanel_Group2);
            app.OffLampLabel.Text = 'Off';
            app.Lamp_Off_Group2 = uilamp(app.StatusPanel_Group2);
            app.Label_FallDetection_Group1_3 = uilabel(app.StatusPanel_Group2);
            app.Label_FallDetection_Group1_3.Text = 'Louver Status';
            app.OnLampLabel_4 = uilabel(app.StatusPanel_Group2);
            app.OnLampLabel_4.Text = 'Open';
            app.Lamp_On_Group1_3 = uilamp(app.StatusPanel_Group2);
            app.CloseLabel_2 = uilabel(app.StatusPanel_Group2);
            app.CloseLabel_2.Text = 'Close';
            app.Lamp_Off_Group1_3 = uilamp(app.StatusPanel_Group2);
            app.StateChangeLogPanel_Group2 = uipanel(app.WestGroupTab);
            app.StateChangeLogPanel_Group2.Title = 'State Change Log';
            app.UITable_Group2 = uitable(app.StateChangeLogPanel_Group2);
            app.UITable_Group2.ColumnName = {'Update'; 'Time'; 'IP'; 'Last Status'};
            app.UITable_Group2.RowName = {};
            app.LouverAnglePanel_Group2 = uipanel(app.WestGroupTab);
            app.LouverAnglePanel_Group2.Title = 'Louver Angle';
            app.ManualControlSwitchLabel_3 = uilabel(app.LouverAnglePanel_Group2);
            app.ManualControlSwitchLabel_3.Text = 'Manual Control';
            app.ManualSwitch_Group2 = uiswitch(app.LouverAnglePanel_Group2, 'rocker');
            app.ManualSwitch_Group2.ValueChangedFcn = createCallbackFcn(app, @ManualSwitch_Group2ValueChanged, true);
            app.OpenButton_G2 = uibutton(app.LouverAnglePanel_Group2, 'push');
            app.OpenButton_G2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_G2Pushed, true);
            app.OpenButton_G2.Icon = 'Open.png';
            app.OpenButton_G2.Text = 'Open';
            app.CloseButton_G2 = uibutton(app.LouverAnglePanel_Group2, 'push');
            app.CloseButton_G2.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_G2Pushed, true);
            app.CloseButton_G2.Icon = 'Close.png';
            app.CloseButton_G2.Text = 'Close';
            app.STOPG1_2 = uibutton(app.LouverAnglePanel_Group2, 'push');
            app.STOPG1_2.ButtonPushedFcn = createCallbackFcn(app, @STOPG1_2ButtonPushed, true);
            app.STOPG1_2.Icon = 'stop.png';
            app.STOPG1_2.Text = '';
            app.WeatherConditionPanel_2 = uipanel(app.WestGroupTab);
            app.WeatherConditionPanel_2.Title = 'Weather Condition';
            app.Label_SolarRadiation_2 = uilabel(app.WeatherConditionPanel_2);
            app.Label_SolarRadiation_2.Text = 'RH (%)';
            app.Label_SkyCondition_2 = uilabel(app.WeatherConditionPanel_2);
            app.Label_SkyCondition_2.Text = 'Sky Condition';
            app.Label_TimeDate_2 = uilabel(app.WeatherConditionPanel_2);
            app.Label_TimeDate_2.Text = 'Temperature (°F)';
            app.Icon2 = uiimage(app.WeatherConditionPanel_2);
            app.RHG2 = uilabel(app.WeatherConditionPanel_2);
            app.RHG2.Text = 'RHG2';
            app.TempG2 = uilabel(app.WeatherConditionPanel_2);
            app.TempG2.Text = 'TempG2';
            app.ImgG2 = uiimage(app.WestGroupTab);
            app.ImgG2.ImageSource = 'LouverGroup2.png';
            app.Label_IW = uilabel(app.UIFigure);
            app.Label_IW.Text = 'IW Louver Control Panel  Device Manager Edition';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end