classdef User < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainTable                  matlab.ui.container.TabGroup
        WeatherConditionTab        matlab.ui.container.Tab
        Label_SkyCondition_3       matlab.ui.control.Label
        Icon                       matlab.ui.control.Image
        RHGaugeLabel               matlab.ui.control.Label
        RHG                        matlab.ui.control.NinetyDegreeGauge
        TemperatureFGaugeLabel     matlab.ui.control.Label
        TempG                      matlab.ui.control.NinetyDegreeGauge
        EastGroupTab               matlab.ui.container.Tab
        LouverAnglePanel_Group1    matlab.ui.container.Panel
        OpenButton_G1              matlab.ui.control.Button
        CloseButton_G1             matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        ManualSwitchLabel          matlab.ui.control.Label
        ManualSwitch_Group1        matlab.ui.control.Switch
        LouverClosedLabel_2        matlab.ui.control.Label
        Lamp_Off_Group1_2          matlab.ui.control.Lamp
        OnLampLabel_3              matlab.ui.control.Label
        Lamp_On_Group1_2           matlab.ui.control.Lamp
        ImgG1                      matlab.ui.control.Image
        WestGroupTab               matlab.ui.container.Tab
        ImgG1_2                    matlab.ui.control.Image
        LouverAnglePanel_Group1_2  matlab.ui.container.Panel
        Image_3                    matlab.ui.control.Image
        Image2_3                   matlab.ui.control.Image
        OnLampLabel_4              matlab.ui.control.Label
        Lamp_On_Group1_3           matlab.ui.control.Lamp
        LouverCloseLabel           matlab.ui.control.Label
        Lamp_Off_Group1_3          matlab.ui.control.Lamp
        CloseButton_G2             matlab.ui.control.Button
        ManualSwitchLabel_2        matlab.ui.control.Label
        ManualSwitch_Group2        matlab.ui.control.Switch
        OpenButton_G2              matlab.ui.control.Button
        Label_IW                   matlab.ui.control.Label
        UpdateButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         %read data from simulink
         temp = xlsread('weather.csv','B9:B9');
         RH1 = xlsread('weather.csv','B13:B13');
         weather = xlsread('weather.csv','B25:B25');   
         %temp
         app.TempG.Value= temp;
         %RH
         app.RHG.Value= RH1;
         % Sky Condition
         if isequal(weather,0)
             app.Icon.ImageSource = 'sun.png';
         elseif isequal(weather,1)
             app.Icon.ImageSource = 'cloud_full.png';
         else
             app.Icon.ImageSource ='Cloud.png';
         end

         % check manual control state 
            % Group2 manual
                valueManualIni2 = app.ManualSwitch_Group2.Value;
                if isequal(valueManualIni2,'On')
                    app.OpenButton_G2.Enable = 'On';
                    app.CloseButton_G2.Enable = 'On';
                    
                else 
                    app.OpenButton_G2.Enable = 'Off';
                    app.CloseButton_G2.Enable = 'Off';
                    
                end
                 % Group1 manul
                valueManualIni1 = app.ManualSwitch_Group1.Value;
                if isequal(valueManualIni1,'On')
                    app.OpenButton_G1.Enable = 'On';
                    app.CloseButton_G1.Enable = 'On';
                    
                else 
                    app.OpenButton_G1.Enable = 'Off';
                    app.CloseButton_G1.Enable = 'Off';
                end
        

        end

        % Callback function
        function ManualSwitch_Group2ValueChanged(app, event)
            valueManualCha2 = app.ManualSwitch_Group2.Value;
                if isequal(valueManualCha2,'On')
                    app.OpenButton_G2.Enable = 'On';
                    app.CloseButton_G2.Enable = 'On';
                    M2_current = 1;
                else 
                    app.OpenButton_G2.Enable = 'Off';
                    app.CloseButton_G2.Enable = 'Off';
                    M2_current = 0;
                end
               xlswrite('G2M_current.xls',M2_current,'Sheet1','A1');
               xlsappend('G2M_log.xls',M2_current,'Sheet1');
                    
        end

        % Callback function
        function ManualSwitch_Group1ValueChanged(app, event)
            valueManualCha1 = app.ManualSwitch_Group1.Value;
                if isequal(valueManualCha1,'On')
                    app.OpenButton_G1.Enable = 'On';
                    app.CloseButton_G1.Enable = 'On';
                    M1_current = 1;
                else 
                    app.OpenButton_G1.Enable = 'Off';
                    app.CloseButton_G1.Enable = 'Off';
                    M1_current = 0;
                    
                end
               xlswrite('G1M_current.xls',M1_current,'Sheet1','A1');
               xlsappend('G1M_log.xls',M1_current,'Sheet1');
        end

        % Button pushed function: OpenButton_G1
        function OpenButton_G1Pushed(app, event)
            xlswrite('G1A_current.xls',1,'Sheet1','A1');
            xlsappend('G1A_log.xls',1,'Sheet1');
        end

        % Button pushed function: CloseButton_G1
        function CloseButton_G1Pushed(app, event)
            xlswrite('G1A_current.xls',2,'Sheet1','A1');
            xlsappend('G1A_log.xls',2,'Sheet1');
        end

        % Button pushed function: OpenButton_G2
        function OpenButton_G2Pushed(app, event)
            xlswrite('G2A_current.xls',1,'Sheet1','A1');
            xlsappend('G2A_log.xls',1,'Sheet1');
        end

        % Button pushed function: CloseButton_G2
        function CloseButton_G2Pushed(app, event)
            xlswrite('G2A_current.xls',2,'Sheet1','A1');
            xlsappend('G2A_log.xls',2,'Sheet1'); 
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
         %read data from simulink
         temp = xlsread('weather.csv','B9:B9');
         RH1 = xlsread('weather.csv','B13:B13');
         weather = xlsread('weather.csv','B25:B25');   
         %temp
         app.TempG.Value= temp;
         %RH
         app.RHG.Value= RH1;
         % Sky Condition
         if isequal(weather,0)
             app.Icon.ImageSource = 'sun.png';
         elseif isequal(weather,1)
             app.Icon.ImageSource = 'cloud_full.png';
         else
             app.Icon.ImageSource ='Cloud.png';
         end

         % check manual control state 
            % Group2 manual
                valueManualIni2 = app.ManualSwitch_Group2.Value;
                if isequal(valueManualIni2,'On')
                    app.OpenButton_G2.Enable = 'On';
                    app.CloseButton_G2.Enable = 'On';
                    
                else 
                    app.OpenButton_G2.Enable = 'Off';
                    app.CloseButton_G2.Enable = 'Off';
                    
                end
                 % Group1 manul
                valueManualIni1 = app.ManualSwitch_Group1.Value;
                if isequal(valueManualIni1,'On')
                    app.OpenButton_G1.Enable = 'On';
                    app.CloseButton_G1.Enable = 'On';
                    
                else 
                    app.OpenButton_G1.Enable = 'Off';
                    app.CloseButton_G1.Enable = 'Off';
                end
                
        end

        % Value changed function: ManualSwitch_Group1
        function ManualSwitch_Group1ValueChanged2(app, event)
            valueManualCha1 = app.ManualSwitch_Group1.Value;
                if isequal(valueManualCha1,'On')

                    app.OpenButton_G1.Enable = 'On';
                    app.CloseButton_G1.Enable = 'On';
                    M1_current = 1;
                else 

                    app.OpenButton_G1.Enable = 'Off';
                    app.CloseButton_G1.Enable = 'Off';
                    M1_current = 0;
                    
                end
               xlswrite('G1M_current.xls',M1_current,'Sheet1','A1');
               xlsappend('G1M_log.xls',M1_current,'Sheet1');
            
        end

        % Value changed function: ManualSwitch_Group2
        function ManualSwitch_Group2ValueChanged2(app, event)
            valueManualCha2 = app.ManualSwitch_Group2.Value;
                if isequal(valueManualCha2,'On')

                    app.OpenButton_G2.Enable = 'On';
                    app.CloseButton_G2.Enable = 'On';
                    M2_current = 1;
                else 

                    app.OpenButton_G2.Enable = 'Off';
                    app.CloseButton_G2.Enable = 'Off';
                    M2_current = 0;
                end
               xlswrite('G2M_current.xls',M2_current,'Sheet1','A1');
               xlsappend('G2M_log.xls',M2_current,'Sheet1');
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainTable = uitabgroup(app.UIFigure);
            app.WeatherConditionTab = uitab(app.MainTable);
            app.WeatherConditionTab.Title = 'Weather Condition';
            app.Label_SkyCondition_3 = uilabel(app.WeatherConditionTab);
            app.Label_SkyCondition_3.Text = 'Sky Condition';
            app.Icon = uiimage(app.WeatherConditionTab);
            app.RHGaugeLabel = uilabel(app.WeatherConditionTab);
            app.RHGaugeLabel.Text = 'RH %';
            app.RHG = uigauge(app.WeatherConditionTab, 'ninetydegree');
            app.TemperatureFGaugeLabel = uilabel(app.WeatherConditionTab);
            app.TemperatureFGaugeLabel.Text = 'Temperature °F ';
            app.TempG = uigauge(app.WeatherConditionTab, 'ninetydegree');
            app.TempG.ScaleDirection = 'counterclockwise';
            app.EastGroupTab = uitab(app.MainTable);
            app.EastGroupTab.Title = 'East Group';
            app.LouverAnglePanel_Group1 = uipanel(app.EastGroupTab);
            app.LouverAnglePanel_Group1.Title = 'Louver Angle Control';
            app.OpenButton_G1 = uibutton(app.LouverAnglePanel_Group1, 'push');
            app.OpenButton_G1.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_G1Pushed, true);
            app.OpenButton_G1.Icon = 'Open.png';
            app.OpenButton_G1.Text = 'Open';
            app.CloseButton_G1 = uibutton(app.LouverAnglePanel_Group1, 'push');
            app.CloseButton_G1.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_G1Pushed, true);
            app.CloseButton_G1.Icon = 'Close.png';
            app.CloseButton_G1.Text = 'Close';
            app.Image = uiimage(app.LouverAnglePanel_Group1);
            app.Image.ImageSource = 'LouverO.jpg';
            app.Image2 = uiimage(app.LouverAnglePanel_Group1);
            app.Image2.ImageSource = 'LouverC.jpg';
            app.ManualSwitchLabel = uilabel(app.LouverAnglePanel_Group1);
            app.ManualSwitchLabel.Text = 'Manual';
            app.ManualSwitch_Group1 = uiswitch(app.LouverAnglePanel_Group1, 'slider');
            app.ManualSwitch_Group1.ValueChangedFcn = createCallbackFcn(app, @ManualSwitch_Group1ValueChanged2, true);
            app.LouverClosedLabel_2 = uilabel(app.LouverAnglePanel_Group1);
            app.LouverClosedLabel_2.Text = 'Louver Closed';
            app.Lamp_Off_Group1_2 = uilamp(app.LouverAnglePanel_Group1);
            app.OnLampLabel_3 = uilabel(app.LouverAnglePanel_Group1);
            app.OnLampLabel_3.Text = 'Louver Open';
            app.Lamp_On_Group1_2 = uilamp(app.LouverAnglePanel_Group1);
            app.ImgG1 = uiimage(app.EastGroupTab);
            app.ImgG1.ImageSource = 'LouverGroup2.png';
            app.WestGroupTab = uitab(app.MainTable);
            app.WestGroupTab.Title = 'West Group';
            app.ImgG1_2 = uiimage(app.WestGroupTab);
            app.ImgG1_2.ImageSource = 'LouverGroup1.png';
            app.LouverAnglePanel_Group1_2 = uipanel(app.WestGroupTab);
            app.LouverAnglePanel_Group1_2.Title = 'Louver Angle Control';
            app.Image_3 = uiimage(app.LouverAnglePanel_Group1_2);
            app.Image_3.ImageSource = 'LouverO.jpg';
            app.Image2_3 = uiimage(app.LouverAnglePanel_Group1_2);
            app.Image2_3.ImageSource = 'LouverC.jpg';
            app.OnLampLabel_4 = uilabel(app.LouverAnglePanel_Group1_2);
            app.OnLampLabel_4.Text = 'Louver Open';
            app.Lamp_On_Group1_3 = uilamp(app.LouverAnglePanel_Group1_2);
            app.LouverCloseLabel = uilabel(app.LouverAnglePanel_Group1_2);
            app.LouverCloseLabel.Text = 'Louver Close';
            app.Lamp_Off_Group1_3 = uilamp(app.LouverAnglePanel_Group1_2);
            app.CloseButton_G2 = uibutton(app.LouverAnglePanel_Group1_2, 'push');
            app.CloseButton_G2.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_G2Pushed, true);
            app.CloseButton_G2.Icon = 'Close.png';
            app.CloseButton_G2.Text = 'Close';
            app.ManualSwitchLabel_2 = uilabel(app.LouverAnglePanel_Group1_2);
            app.ManualSwitchLabel_2.Text = 'Manual';
            app.ManualSwitch_Group2 = uiswitch(app.LouverAnglePanel_Group1_2, 'slider');
            app.ManualSwitch_Group2.ValueChangedFcn = createCallbackFcn(app, @ManualSwitch_Group2ValueChanged2, true);
            app.OpenButton_G2 = uibutton(app.LouverAnglePanel_Group1_2, 'push');
            app.OpenButton_G2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_G2Pushed, true);
            app.OpenButton_G2.Icon = 'Open.png';
            app.OpenButton_G2.Text = 'Open';
            app.Label_IW = uilabel(app.UIFigure);
            app.Label_IW.Text = 'IW Louver Control Panel';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = User

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end